classdef GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileBrowserButton_2             matlab.ui.control.Button
        FiletoreadDropDown_2            matlab.ui.control.DropDown
        FiletoreadDropDownLabel_2       matlab.ui.control.Label
        SparseEDAPanel                  matlab.ui.container.Panel
        SparseCheckBox                  matlab.ui.control.CheckBox
        FileBrowserButton               matlab.ui.control.Button
        PlotPreprocessedSignalCheckBox  matlab.ui.control.CheckBox
        PlotPhasicCheckBox              matlab.ui.control.CheckBox
        PlotTonicCheckBox               matlab.ui.control.CheckBox
        ResetTimesButton                matlab.ui.control.Button
        InsertTimeButton                matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        Timesofstimulation              matlab.ui.control.EditField
        TimesofstimulationhhmmssEditFieldLabel  matlab.ui.control.Label
        FiletoreadDropDown              matlab.ui.control.DropDown
        FiletoreadDropDownLabel         matlab.ui.control.Label
        NeuroKitProcessingPanel         matlab.ui.container.Panel
        NeurokitCheckBox                matlab.ui.control.CheckBox
        SCRMethod                       matlab.ui.control.DropDown
        FindSCRMethodLabel              matlab.ui.control.Label
        DecompMethod                    matlab.ui.control.DropDown
        TonicandPhasicDecompositionMethodDropDownLabel  matlab.ui.control.Label
        PreProcessingPanel              matlab.ui.container.Panel
        EnterFilterOrderEditField       matlab.ui.control.NumericEditField
        EnterFilterOrderEditFieldLabel  matlab.ui.control.Label
        SelectcutoffFrequencySlider     matlab.ui.control.Slider
        SelectcutoffFrequencySliderLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fs=3;
        fs_2=4;
        DirPath
        DirPath_2
        %['C:\Lilach\Technion\Project A']
        %['C:\Users\alonz\OneDrive - Technion\תואר\סמסטר 6\פרויקט\' ...
        %        'project - Stress Detection with a Smart Ring\Ring Samples\CSV'];   
        Time
        Time_2
        secsFromStart
        raw
        raw_2
        acc
        st
        ii
        MM
        SCR
        stimulationTimes = NaT(1);


    end
    
    methods (Access = private)
        function  modifiedPlot(app, axes, preData, tonic, phasic, tEDA_peaks,ampEDA_peaks,type)
            if app.PlotPreprocessedSignalCheckBox.Value
                plot(axes,app.Time,preData,'DisplayName', ...
                    'Preproccesed Data'); hold(axes,'on')
            end
            if app.PlotTonicCheckBox.Value
                plot(axes,app.Time,tonic,'DisplayName','Tonic'); hold(axes,'on')
            end
            if app.PlotPhasicCheckBox.Value
                plot(axes,app.Time,phasic,'DisplayName','Phasic'); hold(axes,'on')
                if exist('type','var')
                    plot(axes,tEDA_peaks,ampEDA_peaks,'o','DisplayName','SCR');
                end
            end

            if ~all(isnat(app.stimulationTimes))
                tStim = timeofday(datetime(app.stimulationTimes(2:end),'InputFormat','HH:mm:ss'));
                xline(axes,tStim,'DisplayName','Event');
            end

            grid(axes,'on');
            legend(axes);
            hold(axes,'off')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            
        end

        % Value changed function: FiletoreadDropDown
        function FiletoreadDropDownValueChanged(app, event)
            %read data again:
            fileName = app.FiletoreadDropDown.Value;
            filePath = append(app.DirPath,append('\',fileName));
            T = readtable(filePath);
            app.Time = timeofday(T.date_time);
            app.secsFromStart = T.time_sec_;
            app.raw = T.raw;
            app.acc = [T.ax, T.ay, T.az];
            app.st = T.st;
            app.ii = T.ii;
            app.MM = T.mm;
            app.SCR = T.scr;
            %reset the stimulation times array:
            app.stimulationTimes = NaT(1);


            fileName_2 = app.FiletoreadDropDown_2.Value;
            filePath_2 = append(app.DirPath_2,append('\',fileName_2));
            T_2 = readtable(filePath_2);
            app.raw_2 = table2array(T_2);
            app.Time_2 = (0:(length(app.raw_2)-1)*(1/app.fs_2));


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %preprocess:
            order = app.EnterFilterOrderEditField.Value;
            cutoffFreq = app.SelectcutoffFrequencySlider.Value;
            preProccesedData = preprocess(app.raw,app.fs,order,cutoffFreq);

            if app.NeurokitCheckBox.Value == true
                %extract tonic and phasic components:
                phasicMethod = app.DecompMethod.Value;
                tonic_1 = pyrunfile('neurokitMatlab.py', "tonic", ...
                    eda_signal = preProccesedData, phasicMethod = phasicMethod, peakMethod='', fs=app.fs);
                phasic_1 = pyrunfile('neurokitMatlab.py', "phasic", ...
                    eda_signal = preProccesedData, phasicMethod = phasicMethod, peakMethod='', fs=app.fs);
                % transfer from pylist to matlab array:
                tonic_1 = double(tonic_1);
                phasic_1 = double(phasic_1);
    
                %find peaks:
                peakMethod = app.SCRMethod.Value;
                EDA_peaks = pyrunfile('neurokitMatlab.py', "EDA_peaks", ...
                    eda_signal = phasic_1, phasicMethod = '',peakMethod=peakMethod);
                %find the time and amplitude of the peaks to plot them:
                tEDA_peaks = app.Time(double(EDA_peaks)+1+1*strcmp(peakMethod,'Kim2004'));
                ampEDA_peaks = phasic_1(double(EDA_peaks)+1+1*strcmp(peakMethod,'Kim2004'));
                modifiedPlot(app, app.UIAxes1,preProccesedData,tonic_1,phasic_1, ...
                    tEDA_peaks,ampEDA_peaks,"Neurokit")
            end
            if app.SparseCheckBox.Value == true
                sr = 3; %sample rate
                graphics = 0;
                epsilon  = 1;
                Kmax   = 40;
                dmin = 1.25*sr;  % Minimum distance between activations
                rho = 0.025;
                [phasic_2, tonic_2, MSE] = sparsEDA(preProccesedData,sr,graphics,epsilon,Kmax,dmin,rho);
                phasic_2 = phasic_2(1:end-1);
                tonic_2 = tonic_2(1:end-1);
                modifiedPlot(app, app.UIAxes1,preProccesedData,tonic_2,phasic_2)
            end


            %%%%%%%%%%%%%%%%%%%%%%%

            preProccesedData_2 = preprocess(app.raw_2,app.fs,order,cutoffFreq);

            if app.NeurokitCheckBox.Value == true
                %extract tonic and phasic components:
                phasicMethod = app.DecompMethod.Value;
                tonic_1 = pyrunfile('neurokitMatlab.py', "tonic", ...
                    eda_signal = preProccesedData_2, phasicMethod = phasicMethod, peakMethod='', fs=app.fs_2);
                phasic_1 = pyrunfile('neurokitMatlab.py', "phasic", ...
                    eda_signal = preProccesedData_2, phasicMethod = phasicMethod, peakMethod='',fs=app.fs_2);
                % transfer from pylist to matlab array:
                tonic_1 = double(tonic_1);
                phasic_1 = double(phasic_1);
    
                %find peaks:
                peakMethod = app.SCRMethod.Value;
                EDA_peaks = pyrunfile('neurokitMatlab.py', "EDA_peaks", ...
                    eda_signal = phasic_1, phasicMethod = '',peakMethod=peakMethod);
                %find the time and amplitude of the peaks to plot them:
                tEDA_peaks = app.Time(double(EDA_peaks)+1+1*strcmp(peakMethod,'Kim2004'));
                ampEDA_peaks = phasic_1(double(EDA_peaks)+1+1*strcmp(peakMethod,'Kim2004'));
                modifiedPlot(app, app.UIAxes2,preProccesedData,tonic_1,phasic_1, ...
                    tEDA_peaks,ampEDA_peaks,"Neurokit")
            end
            if app.SparseCheckBox.Value == true
                sr = 3; %sample rate
                graphics = 0;
                epsilon  = 1;
                Kmax   = 40;
                dmin = 1.25*sr;  % Minimum distance between activations
                rho = 0.025;
                [phasic_2, tonic_2, MSE] = sparsEDA(preProccesedData,sr,graphics,epsilon,Kmax,dmin,rho);
                phasic_2 = phasic_2(1:end-1);
                tonic_2 = tonic_2(1:end-1);
                modifiedPlot(app, app.UIAxes2,preProccesedData,tonic_2,phasic_2)
            end


            %plot:


        end

        % Button pushed function: InsertTimeButton
        function InsertTimeButtonPushed(app, event)
            stimulationTime = datetime(app.Timesofstimulation.Value);
            app.stimulationTimes(end+1) = stimulationTime;
            app.Timesofstimulation.Value = '';


        end

        % Button pushed function: ResetTimesButton
        function ResetTimesButtonPushed(app, event)
            app.stimulationTimes = NaT(1);
        end

        % Button pushed function: FileBrowserButton
        function FileBrowserButtonPushed(app, event)
            app.DirPath = uigetdir(['C:\Users\alonz\OneDrive - Technion\תואר\סמסטר 6\פרויקט\' ...
               'project - Stress Detection with a Smart Ring\Ring Samples\CSV']  );

            filesList = dir(app.DirPath);
            filesNames = strings;
            
            for jj = 1:(length(filesList)-2)
                filesNames(end+1,:) = filesList(jj+2).name;
            end
            filesNames = sort(filesNames(2:end));
            app.FiletoreadDropDown.Items = filesNames;
            FiletoreadDropDownValueChanged(app);
            
        end

        % Value changed function: SparseCheckBox
        function SparseCheckBoxValueChanged(app, event)
            value = app.SparseCheckBox.Value;
            if value == true
                app.NeurokitCheckBox.Value = false;
            end
        end

        % Value changed function: NeurokitCheckBox
        function NeurokitCheckBoxValueChanged(app, event)
            value = app.NeurokitCheckBox.Value;
            if value == true
                app.SparseCheckBox.Value = false;
            end
        end

        % Button pushed function: FileBrowserButton_2
        function FileBrowserButton_2Pushed(app, event)
             app.DirPath_2 = uigetdir(['C:\Users\alonz\OneDrive - Technion\תואר\סמסטר 6\פרויקט\' ...
               'project - Stress Detection with a Smart Ring\Ring Samples\CSV']  );

            filesList = dir(app.DirPath_2);
            filesNames = strings;
            
            for jj = 1:(length(filesList)-2)
                filesNames(end+1,:) = filesList(jj+2).name;
            end
            filesNames = sort(filesNames(2:end));
            app.FiletoreadDropDown.Items = filesNames;
            FiletoreadDropDownValueChanged(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PreProcessingPanel = uipanel(app.UIFigure);
            app.PreProcessingPanel.Title = 'PreProcessing';
            app.SelectcutoffFrequencySliderLabel = uilabel(app.PreProcessingPanel);
            app.SelectcutoffFrequencySliderLabel.Text = 'Select cutoff Frequency';
            app.SelectcutoffFrequencySlider = uislider(app.PreProcessingPanel);
            app.SelectcutoffFrequencySlider.Limits = [0.1 1];
            app.SelectcutoffFrequencySlider.MajorTicks = [0.1 0.5 1];
            app.SelectcutoffFrequencySlider.MinorTicks = [0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.SelectcutoffFrequencySlider.Value = 0.5;
            app.EnterFilterOrderEditFieldLabel = uilabel(app.PreProcessingPanel);
            app.EnterFilterOrderEditFieldLabel.Text = 'Enter Filter Order';
            app.EnterFilterOrderEditField = uieditfield(app.PreProcessingPanel, 'numeric');
            app.EnterFilterOrderEditField.Value = 8;
            app.NeuroKitProcessingPanel = uipanel(app.UIFigure);
            app.NeuroKitProcessingPanel.Title = 'NeuroKit Processing';
            app.TonicandPhasicDecompositionMethodDropDownLabel = uilabel(app.NeuroKitProcessingPanel);
            app.TonicandPhasicDecompositionMethodDropDownLabel.Text = 'Tonic and Phasic Decomposition Method';
            app.DecompMethod = uidropdown(app.NeuroKitProcessingPanel);
            app.DecompMethod.Items = {'Smooth Median', 'High Pass', 'Convex'};
            app.DecompMethod.Value = 'Smooth Median';
            app.FindSCRMethodLabel = uilabel(app.NeuroKitProcessingPanel);
            app.FindSCRMethodLabel.Text = 'Find SCR Method';
            app.SCRMethod = uidropdown(app.NeuroKitProcessingPanel);
            app.SCRMethod.Items = {'NeuroKit', 'Kim2004', 'Nabian2018', 'Gamboa2008', 'Vanhalem2020'};
            app.SCRMethod.Value = 'NeuroKit';
            app.NeurokitCheckBox = uicheckbox(app.NeuroKitProcessingPanel);
            app.NeurokitCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeurokitCheckBoxValueChanged, true);
            app.NeurokitCheckBox.Text = 'Use This Method';
            app.FiletoreadDropDownLabel = uilabel(app.UIFigure);
            app.FiletoreadDropDownLabel.Text = 'File to read';
            app.FiletoreadDropDown = uidropdown(app.UIFigure);
            app.FiletoreadDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletoreadDropDownValueChanged, true);
            app.TimesofstimulationhhmmssEditFieldLabel = uilabel(app.UIFigure);
            app.TimesofstimulationhhmmssEditFieldLabel.Text = 'Times of stimulation (hh:mm:ss)';
            app.Timesofstimulation = uieditfield(app.UIFigure, 'text');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InsertTimeButton = uibutton(app.UIFigure, 'push');
            app.InsertTimeButton.ButtonPushedFcn = createCallbackFcn(app, @InsertTimeButtonPushed, true);
            app.InsertTimeButton.Text = 'Insert Time';
            app.ResetTimesButton = uibutton(app.UIFigure, 'push');
            app.ResetTimesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTimesButtonPushed, true);
            app.ResetTimesButton.Text = 'Reset Times';
            app.PlotTonicCheckBox = uicheckbox(app.UIFigure);
            app.PlotTonicCheckBox.Text = 'Plot Tonic';
            app.PlotTonicCheckBox.Value = true;
            app.PlotPhasicCheckBox = uicheckbox(app.UIFigure);
            app.PlotPhasicCheckBox.Text = 'Plot Phasic';
            app.PlotPhasicCheckBox.Value = true;
            app.PlotPreprocessedSignalCheckBox = uicheckbox(app.UIFigure);
            app.PlotPreprocessedSignalCheckBox.Text = 'Plot Preprocessed Signal';
            app.PlotPreprocessedSignalCheckBox.Value = true;
            app.FileBrowserButton = uibutton(app.UIFigure, 'push');
            app.FileBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @FileBrowserButtonPushed, true);
            app.FileBrowserButton.Text = 'File Browser';
            app.SparseEDAPanel = uipanel(app.UIFigure);
            app.SparseEDAPanel.Title = 'SparseEDA';
            app.SparseCheckBox = uicheckbox(app.SparseEDAPanel);
            app.SparseCheckBox.ValueChangedFcn = createCallbackFcn(app, @SparseCheckBoxValueChanged, true);
            app.SparseCheckBox.Text = 'Use This Method';
            app.FiletoreadDropDownLabel_2 = uilabel(app.UIFigure);
            app.FiletoreadDropDownLabel_2.Text = 'File to read';
            app.FiletoreadDropDown_2 = uidropdown(app.UIFigure);
            app.FileBrowserButton_2 = uibutton(app.UIFigure, 'push');
            app.FileBrowserButton_2.ButtonPushedFcn = createCallbackFcn(app, @FileBrowserButton_2Pushed, true);
            app.FileBrowserButton_2.Text = 'File Browser';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end